classdef Gui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        apply_button                  matlab.ui.control.Button
        playsound                     matlab.ui.control.Button
        pausarButton                  matlab.ui.control.Button
        reproduzirButton              matlab.ui.control.Button
        carregarButton                matlab.ui.control.Button
        unitgroup                     matlab.ui.container.ButtonGroup
        uipanel5                      matlab.ui.container.Panel
        t2                            matlab.ui.control.Label
        high_cut_frequency_slider     matlab.ui.control.Slider
        half_cut_switch               matlab.ui.control.CheckBox
        uipanel8                      matlab.ui.container.Panel
        t5                            matlab.ui.control.Label
        high_frequecy_damping_slider  matlab.ui.control.Slider
        high_frequecy_check           matlab.ui.control.CheckBox
        uipanel9                      matlab.ui.container.Panel
        t6                            matlab.ui.control.Label
        wet_dry_mix_slider            matlab.ui.control.Slider
        wet_dry_mix_check             matlab.ui.control.CheckBox
        uipanel7                      matlab.ui.container.Panel
        t4                            matlab.ui.control.Label
        decay_factor_slider           matlab.ui.control.Slider
        decay_factor_check            matlab.ui.control.CheckBox
        uipanel6                      matlab.ui.container.Panel
        t3                            matlab.ui.control.Label
        diffusion_slider              matlab.ui.control.Slider
        diffusion_check               matlab.ui.control.CheckBox
        text22                        matlab.ui.control.Label
        uipanel2                      matlab.ui.container.Panel
        t11                           matlab.ui.control.Label
        pre_delay_slider              matlab.ui.control.Slider
        pre_delay_check               matlab.ui.control.CheckBox
        uipanel1                      matlab.ui.container.Panel
        text9                         matlab.ui.control.Label
        text8                         matlab.ui.control.Label
        axes2                         matlab.ui.control.UIAxes
        axes1                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function y = applyFilters(app, handles)
            global x Fs;
            %y = x;
            pre_delay = get(handles.pre_delay_check, 'Value');
            half_cut_frequency = get(handles.half_cut_switch, 'Value');
            diffusion = get(handles.diffusion_check, 'Value');
            decay_factor = get(handles.decay_factor_check, 'Value');
            high_frequency_damping= get(handles.high_frequecy_check, 'Value');
            wet_dry_mix = get(handles.wet_dry_mix_check, 'Value');


            %reverb = reverberator('PreDelay',1,'WetDryMix',0.5);
            %audioOut = reverb(audio)
            %fileReader = dsp.AudioFileReader('FunkyDrums-44p1-stereo-25secs.mp3','SamplesPerFrame',2500);
            %deviceWriter = audioDeviceWriter('SampleRate',fileReader.SampleRate);
            %global audio
            %tic
            %while toc < 10
             %   audio = fileReader();
              %  deviceWriter(audio);
            %end
            %release(fileReader)
            
            audio=x;


            if pre_delay == 1
            gain = get(handles.pre_delay_slider, 'Value');
            set(handles.t11,'String',gain);
            reverb = reverberator('PreDelay',gain);
            y = reverb(audio) ;
            else
            gain=0;
            end
            
            if half_cut_frequency == 1
            
             tone = get(handles.high_cut_frequency_slider, 'Value');
             reverb = reverberator('PreDelay',gain,'HighCutFrequency',tone);
             y = reverb(audio) ;
            else
             tone=20000;
            end
            
            if diffusion== 1
            
              mix = get(handles.diffusion_slider, 'Value');
              reverb = reverberator('PreDelay',gain,'HighCutFrequency',tone,'Diffusion',mix);
              y = reverb(audio);
            else
             mix=0.5;
            end
            
            if decay_factor== 1
                width = get(handles.decay_factor_slider, 'Value');
                reverb = reverberator('PreDelay',gain,'HighCutFrequency',tone,'Diffusion',mix,'DecayFactor',width);
                y = reverb(audio);
            else
            width=0.5;
            end
            
            if high_frequency_damping== 1
              rate = get(handles.high_frequecy_damping_slider, 'Value');
              reverb = reverberator('PreDelay',gain,'HighCutFrequency',tone,'Diffusion',mix,'DecayFactor',width,'HighFrequencyDamping',rate);
              y = reverb(audio) ;
            else
                rate=0.0005;
            end
            
            if wet_dry_mix== 1
              depth = get(handles.wet_dry_mix_slider, 'Value');
              reverb = reverberator('PreDelay',gain,'HighCutFrequency',tone,'Diffusion',mix,'DecayFactor',width,'HighFrequencyDamping',rate,'WetDryMix',depth);
              y = reverb(audio);
            else
             depth=0.3;
            end
        end
        
        function delayButton_Callback(app, hObject, eventdata, handles)
            % hObject    handle to distortionButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of distortionButton
            updateAxesFiltered(app, handles);
        end
        
        function delayDelaySlider_Callback(app, hObject, eventdata, handles)
            % hObject    handle to delayDelaySlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            updateAxesFiltered(app, handles);
        end
        
        function delayDelaySlider_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to delayDelaySlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function delayDepthSlider_Callback(app, hObject, eventdata, handles)
            % hObject    handle to delayDepthSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            updateAxesFiltered(app, handles);
        end
        
        function delayDepthSlider_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to delayDepthSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function delayFeedbackSlider_Callback(app, hObject, eventdata, handles)
            % hObject    handle to delayFeedbackSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            updateAxesFiltered(app, handles);
        end
        
        function delayFeedbackSlider_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to delayFeedbackSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function distortionButton_Callback(app, hObject, eventdata, handles)
            % hObject    handle to distortionButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of distortionButton
            updateAxesFiltered(app, handles);
        end
        
        function distortionGainSlider_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to distortionGainSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function distortionToneSlider_Callback(app, hObject, eventdata, handles)
            % hObject    handle to distortionToneSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            updateAxesFiltered(app, handles);
        end
        
        function distortionToneSlider_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to distortionToneSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function edit6_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function flangerButton_Callback(app, hObject, eventdata, handles)
            % hObject    handle to flangerButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of flangerButton
            updateAxesFiltered(app, handles);
        end
        
        function initialize_gui(app, ~, handles, isreset)
            % If the metricdata field is present and the reproduzirButton flag is false, it means
            % we are we are just re-initializing a GUI by calling it from the cmd line
            % while it is up. So, bail out as we dont want to reproduzirButton the data.
            %
            % handles.metricdata.density = 0;
            % handles.metricdata.volume  = 0;
            %
            % set(handles.density, 'String', handles.metricdata.density);
            % set(handles.volume,  'String', handles.metricdata.volume);
            % set(handles.mass, 'String', 0);
            %
            % set(handles.unitgroup, 'SelectedObject', handles.english);
            %
            % set(handles.text4, 'String', 'lb/cu.in');
            % set(handles.text5, 'String', 'cu.in');
            % set(handles.text6, 'String', 'lb');
            
            % Update handles structure
            guidata(handles.figure1, handles);
        end
        
        function [w, H] = loadFFT(app, x1)
            global Fs;
            w = linspace(0, Fs/2, length(x1));
            H = 20*log10(abs(fftshift(fft(x1))));
            %H =(abs(fftshift(fft(x1))));
        end
        
        function sample_rate_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to sample_rate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function updateAxes(app, handles)
            global x;
            axes(handles.axes1); %set the current axes to axes2
            plot(x);
            title 'GRAPH OF INPUT AUDIO'
            xlabel('Time (s)');
            ylabel('Magnitude');
            [w, H] = loadFFT(app, x);
            axes(handles.axes2);
            plot(w*(0.5*pi), H);
            ylabel('dB')
            xlabel('Frequency Response (HZ)');
        end
        
        function updateAxesFiltered(app, handles)
            global x;
            y = applyFilters(app, handles);
            
            
            if length(x) ~= (length(y)) || ~isequal(x,y)
                axes(handles.axes1); %set the current axes to axes2
                plot(y, '--r');
                title ' Graph After Applying Reverberator'
                xlabel('Time (s)');
                ylabel('Magnitude');
                [w, H] = loadFFT(app, y);
                axes(handles.axes2);
                plot(w*(0.5*pi), H, '--r');
                %plot(y, '--r');
                ylabel('dB')
                xlabel('Frequency Response (HZ)');
            else
                updateAxes(app, handles);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Gui_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Gui (see VARARGIN)
            
            % Choose default command line output for Gui
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            initialize_gui(app, hObject, handles, false);
        end

        % Button pushed function: apply_button
        function apply_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            pre_delay = get(handles.pre_delay_check, 'Value');
            half_cut_frequency = get(handles.half_cut_switch, 'Value');
            diffusion = get(handles.diffusion_check, 'Value');
            decay_factor = get(handles.decay_factor_check, 'Value');
            high_frequency_damping= get(handles.high_frequecy_check, 'Value');
            wet_dry_mix = get(handles.wet_dry_mix_check, 'Value');
            
            if pre_delay||half_cut_frequency||diffusion||decay_factor||high_frequency_damping||wet_dry_mix
             set(handles.text22,'String','Filter Selected');
             updateAxesFiltered(app, handles);
            else
             set(handles.text22,'String','Please Select Atlest One filter');
            end
        end

        % Button pushed function: carregarButton
        function carregarButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to carregarButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global x Fs;
            [filename, filepath]= uigetfile({'*.wav','WAV Files'}, 'Selecione um ï¿½udio wav');
            if (filename ~= 0)
                [x, Fs] = LoadAudio([filepath filename]);
                updateAxes(app, handles);
                %updateAxesFiltered(handles);
            end
        end

        % Value changed function: decay_factor_slider
        function decay_factor_slider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to decay_factor_slider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            %updateAxesFiltered(handles)
            % --- Executes during object creation, after setting all properties.
            gain1_check = get(handles.decay_factor_check, 'Value');
            if gain1_check==1
            gain1 = get(handles.decay_factor_slider, 'Value');
            set(handles.t4,'String',gain1);
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            end
        end

        % Value changed function: diffusion_slider
        function diffusion_slider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to diffusion_slider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            %updateAxesFiltered(handles)
            gain1_check = get(handles.diffusion_check, 'Value');
            if gain1_check==1
            gain1 = get(handles.diffusion_slider, 'Value');
            set(handles.t3,'String',gain1);
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            end
        end

        % Value changed function: high_cut_frequency_slider
        function high_cut_frequency_slider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to high_cut_frequency_slider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            gain1_check = get(handles.half_cut_switch, 'Value');
            if gain1_check==1
            gain1 = get(handles.high_cut_frequency_slider, 'Value');
            set(handles.t2,'String',gain1);
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            end
        end

        % Value changed function: high_frequecy_damping_slider
        function high_frequecy_damping_slider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to high_frequecy_damping_slider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            gain1_check = get(handles.high_frequecy_check, 'Value');
            if gain1_check==1
            gain1 = get(handles.high_frequecy_damping_slider, 'Value');
            set(handles.t5,'String',gain1);
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            end
        end

        % Button pushed function: pausarButton
        function pausarButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pausarbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global Fs;
            y = applyFilters(app, handles);
            audiowrite('output.wav',y,Fs);
        end

        % Button pushed function: playsound
        function playsound_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to playsound (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global x Fs;
            clear sound;
            sound(x, Fs);
        end

        % Value changed function: pre_delay_slider
        function pre_delay_slider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pre_delay_slider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            gain1_check = get(handles.pre_delay_check, 'Value');
            if gain1_check==1
            gain1 = get(handles.pre_delay_slider, 'Value');
            set(handles.t11,'String',gain1);
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            end
        end

        % Button pushed function: reproduzirButton
        function reproduzirButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to reproduzirButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            pre_delay = get(handles.pre_delay_check, 'Value');
            half_cut_frequency = get(handles.half_cut_switch, 'Value');
            diffusion = get(handles.diffusion_check, 'Value');
            decay_factor = get(handles.decay_factor_check, 'Value');
            high_frequency_damping= get(handles.high_frequecy_check, 'Value');
            wet_dry_mix = get(handles.wet_dry_mix_check, 'Value');
            global x Fs;
            if pre_delay||half_cut_frequency||diffusion||decay_factor||high_frequency_damping||wet_dry_mix
             set(handles.text22,'String','Filter Selected');
            y = applyFilters(app, handles);
            clear sound;
            sound(y, Fs);
            else
             set(handles.text22,'String','Please Select Atlest One filter');
            end
        end

        % Value changed function: wet_dry_mix_slider
        function wet_dry_mix_slider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to wet_dry_mix_slider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            gain1_check = get(handles.wet_dry_mix_check, 'Value');
            if gain1_check==1
            gain1 = get(handles.wet_dry_mix_slider, 'Value');
            set(handles.t6,'String',gain1);
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Audio Reverberation';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = ' visualization';
            app.uipanel1.Tag = 'uipanel1';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.uipanel1);
            app.axes2.Tag = 'axes2';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Time Analysis';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Frequency Analysis: Fourier Transform';
            app.unitgroup = uibuttongroup(app.figure1);
            app.unitgroup.Title = 'Filters';
            app.unitgroup.Tag = 'unitgroup';
            app.uipanel2 = uipanel(app.unitgroup);
            app.uipanel2.Title = 'Pre Delay';
            app.uipanel2.Tag = 'uipanel2';
            app.pre_delay_check = uicheckbox(app.uipanel2);
            app.pre_delay_check.Tag = 'pre_delay_check';
            app.pre_delay_slider = uislider(app.uipanel2);
            app.pre_delay_slider.Limits = [0 1];
            app.pre_delay_slider.MajorTicks = [];
            app.pre_delay_slider.ValueChangedFcn = createCallbackFcn(app, @pre_delay_slider_Callback, true);
            app.pre_delay_slider.MinorTicks = [];
            app.pre_delay_slider.Tag = 'pre_delay_slider';
            app.t11 = uilabel(app.uipanel2);
            app.t11.Tag = 't11';
            app.t11.Text = '';
            app.text22 = uilabel(app.unitgroup);
            app.text22.Tag = 'text22';
            app.text22.Text = 'STATUS';
            app.uipanel6 = uipanel(app.unitgroup);
            app.uipanel6.Title = 'Diffusion';
            app.uipanel6.Tag = 'uipanel6';
            app.diffusion_check = uicheckbox(app.uipanel6);
            app.diffusion_check.Tag = 'diffusion_check';
            app.diffusion_slider = uislider(app.uipanel6);
            app.diffusion_slider.Limits = [0 1];
            app.diffusion_slider.MajorTicks = [];
            app.diffusion_slider.ValueChangedFcn = createCallbackFcn(app, @diffusion_slider_Callback, true);
            app.diffusion_slider.MinorTicks = [];
            app.diffusion_slider.Tag = 'diffusion_slider';
            app.t3 = uilabel(app.uipanel6);
            app.t3.Tag = 't3';
            app.t3.Text = '';
            app.uipanel7 = uipanel(app.unitgroup);
            app.uipanel7.Title = 'DecayFactor';
            app.uipanel7.Tag = 'uipanel7';
            app.decay_factor_check = uicheckbox(app.uipanel7);
            app.decay_factor_check.Tag = 'decay_factor_check';
            app.decay_factor_slider = uislider(app.uipanel7);
            app.decay_factor_slider.Limits = [0 1];
            app.decay_factor_slider.MajorTicks = [];
            app.decay_factor_slider.ValueChangedFcn = createCallbackFcn(app, @decay_factor_slider_Callback, true);
            app.decay_factor_slider.MinorTicks = [];
            app.decay_factor_slider.Tag = 'decay_factor_slider';
            app.t4 = uilabel(app.uipanel7);
            app.t4.Tag = 't4';
            app.t4.Text = '';
            app.uipanel9 = uipanel(app.unitgroup);
            app.uipanel9.Title = 'WetDryMix';
            app.uipanel9.Tag = 'uipanel9';
            app.wet_dry_mix_check = uicheckbox(app.uipanel9);
            app.wet_dry_mix_check.Tag = 'wet_dry_mix_check';
            app.wet_dry_mix_slider = uislider(app.uipanel9);
            app.wet_dry_mix_slider.Limits = [0 1];
            app.wet_dry_mix_slider.MajorTicks = [];
            app.wet_dry_mix_slider.ValueChangedFcn = createCallbackFcn(app, @wet_dry_mix_slider_Callback, true);
            app.wet_dry_mix_slider.MinorTicks = [];
            app.wet_dry_mix_slider.Tag = 'wet_dry_mix_slider';
            app.t6 = uilabel(app.uipanel9);
            app.t6.Tag = 't6';
            app.t6.Text = '';
            app.uipanel8 = uipanel(app.unitgroup);
            app.uipanel8.Title = 'Stereo width';
            app.uipanel8.Tag = 'uipanel8';
            app.high_frequecy_check = uicheckbox(app.uipanel8);
            app.high_frequecy_check.Tag = 'high_frequecy_check';
            app.high_frequecy_damping_slider = uislider(app.uipanel8);
            app.high_frequecy_damping_slider.Limits = [0 1];
            app.high_frequecy_damping_slider.MajorTicks = [];
            app.high_frequecy_damping_slider.ValueChangedFcn = createCallbackFcn(app, @high_frequecy_damping_slider_Callback, true);
            app.high_frequecy_damping_slider.MinorTicks = [];
            app.high_frequecy_damping_slider.Tag = 'high_frequecy_damping_slider';
            app.t5 = uilabel(app.uipanel8);
            app.t5.Tag = 't5';
            app.t5.Text = '';
            app.uipanel5 = uipanel(app.unitgroup);
            app.uipanel5.Title = 'HighCutFrequency';
            app.uipanel5.Tag = 'uipanel5';
            app.half_cut_switch = uicheckbox(app.uipanel5);
            app.half_cut_switch.Tag = 'half_cut_switch';
            app.high_cut_frequency_slider = uislider(app.uipanel5);
            app.high_cut_frequency_slider.Limits = [0 20000];
            app.high_cut_frequency_slider.MajorTicks = [];
            app.high_cut_frequency_slider.ValueChangedFcn = createCallbackFcn(app, @high_cut_frequency_slider_Callback, true);
            app.high_cut_frequency_slider.MinorTicks = [];
            app.high_cut_frequency_slider.Tag = 'high_cut_frequency_slider';
            app.t2 = uilabel(app.uipanel5);
            app.t2.Tag = 't2';
            app.t2.Text = '';
            app.carregarButton = uibutton(app.figure1, 'push');
            app.carregarButton.ButtonPushedFcn = createCallbackFcn(app, @carregarButton_Callback, true);
            app.carregarButton.Tag = 'carregarButton';
            app.carregarButton.Text = 'Load Audio';
            app.reproduzirButton = uibutton(app.figure1, 'push');
            app.reproduzirButton.ButtonPushedFcn = createCallbackFcn(app, @reproduzirButton_Callback, true);
            app.reproduzirButton.Tag = 'reproduzirButton';
            app.reproduzirButton.Text = 'Play Sound reverb';
            app.pausarButton = uibutton(app.figure1, 'push');
            app.pausarButton.ButtonPushedFcn = createCallbackFcn(app, @pausarButton_Callback, true);
            app.pausarButton.Tag = 'pausarButton';
            app.pausarButton.Text = 'Create File';
            app.playsound = uibutton(app.figure1, 'push');
            app.playsound.ButtonPushedFcn = createCallbackFcn(app, @playsound_Callback, true);
            app.playsound.Tag = 'playsound';
            app.playsound.Text = 'Play sound';
            app.apply_button = uibutton(app.figure1, 'push');
            app.apply_button.ButtonPushedFcn = createCallbackFcn(app, @apply_button_Callback, true);
            app.apply_button.Tag = 'apply_button';
            app.apply_button.Text = 'Apply Reverberator';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gui_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Gui_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end